classdef LandmarkColors_App < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure_landmarkcolors  matlab.ui.Figure
    uipanel6               matlab.ui.container.Panel
    text22                 matlab.ui.control.Label
    pbSkeletonColor        matlab.ui.control.Button
    text23                 matlab.ui.control.Label
    pbSkeletonColorPred    matlab.ui.control.Button
    text21                 matlab.ui.control.Label
    sldSkeletonLineWidth   matlab.ui.control.Slider
    pnlTab                 matlab.ui.container.Panel
    pnlMarkersLabels       matlab.ui.container.Panel
    tblProps               matlab.ui.control.Table
    uibuttongroup1         matlab.ui.container.ButtonGroup
    text19                 matlab.ui.control.Label
    cbApplyAll             matlab.ui.control.CheckBox
    text12                 matlab.ui.control.Label
    pumShowing             matlab.ui.control.DropDown
    radiobutton_manual     matlab.ui.control.RadioButton
    uipanel_colormap       matlab.ui.container.Panel
    text3                  matlab.ui.control.Label
    text2                  matlab.ui.control.Label
    edit_brightness        matlab.ui.control.EditField
    slider_brightness      matlab.ui.control.Slider
    popupmenu_colormap     matlab.ui.control.DropDown
    axes_colormap          matlab.ui.control.UIAxes
    uipanel_manual         matlab.ui.container.Panel
    radiobutton_colormap   matlab.ui.control.RadioButton
    pbDone                 matlab.ui.control.Button
  end

    
    methods (Access = private)
        function s = MarkerControlsGet(app, handles)
            % Read handles.tblProps into various PV-structs etc that can be used to set
            % HG handles.
            %
            % s: structarr of PV structs
            %
            % s(i).pvLine -- PV struct for line handle
            % s(i).pvTxt -- PV struct for text label
            % s(i).txtOffset -- text offset (in px)
            % Els of s labeled by lbl, pred, imp
            
            uitbl = handles.tblProps;
            assert(isequal(uitbl.RowName,{'Label' 'Prediction'}'));
            
            uitblColumnNameSanitized = regexprep(uitbl.ColumnName,' ','_'); % for eg ML19a
            t = cell2table(uitbl.Data,'VariableNames',uitblColumnNameSanitized);
            
            FLDS_MARKER = {'Marker' 'MarkerSize' 'LineWidth'};
            tLine = t(:,FLDS_MARKER);
            sLine = table2struct(tLine);
            % s = struct();
            % s.pvLine = sLine;
            
            FLDS_TEXT = {'Show_Text_Label','Label_Font_Size' 'Label_Font_Angle'};
            tTxt = t(:,FLDS_TEXT);
            tTxt.Properties.VariableNames = {'Visible' 'FontSize' 'FontAngle'};
            tTxt.Visible = arrayfun(@onIff,tTxt.Visible,'uni',0);
            sTxt = table2struct(tTxt);
            % s.pvTxt = sTxt;
            
            txtOffset = t{:,'Label_Offset'};
            
            s = struct(...
              'landmarkSetType',num2cell(enumeration('LandmarkSetType')),...
              'MarkerProps',num2cell(sLine),...
              'TextProps',num2cell(sTxt),...
              'TextOffset',num2cell(txtOffset) ...
              );
        end
        
        function MarkerControlsSet(app, handles, s)
            % Set tblProps per pvStructs
            %
            % s: as in structure returned by MarkerControlsGet
            
            uitbl = handles.tblProps;
            assert(isequal(uitbl.RowName,{'Label' 'Prediction'}'));
            
            FLDS_MARKER = {'Marker' 'MarkerSize' 'LineWidth'};
            tMrkr = struct2table([s.MarkerProps]');
            assert(isequal(tMrkr.Properties.VariableNames,FLDS_MARKER));
            
            FLDS_TEXT = {'Visible' 'FontSize' 'FontAngle'};
            sTxt = [s.TextProps]';
            sTxt = structrestrictflds(sTxt,FLDS_TEXT);
            sTxt = orderfields(sTxt,FLDS_TEXT);
            tTxt = struct2table(sTxt);
            %assert(isequal(tTxt.Properties.VariableNames,FLDS_TEXT));
            tTxt.Visible = strcmp(tTxt.Visible,'on');
            
            txtOffset = num2cell([s.TextOffset]');
            
            assert(isequal(uitbl.ColumnName,...
              {'Marker' 'MarkerSize' 'LineWidth' 'Show Text Label' 'Label Font Size' 'Label Font Angle' 'Label Offset'}'));
            
            dat = [table2cell(tMrkr) table2cell(tTxt) txtOffset];
            uitbl.Data = dat;
        end
        
        function handles = SaveState(app, handles)
            
            tfApplyAll = handles.cbApplyAll.Value;
            iCS = handles.pumShowing.Value;
            if tfApplyAll
              colorSpecs = handles.colorSpecs;
              for i=1:numel(colorSpecs)
                if i==iCS
                  continue;
                end
                colorSpecs(i).copyColorState(colorSpecs(iCS));
              end
              colorSpecs0 = handles.colorSpecs0;
            else
              colorSpecs = handles.colorSpecs(iCS);
              colorSpecs0 = handles.colorSpecs0(iCS);
            end
            colorSpecs.setManualColorsToColormapIfNec();
            sPropsMrkr = MarkerControlsGet(app, handles);
            sPropsSkel = SkelControlsGet(app, handles);
            
            tfclrchanged = ~arrayfun(@isequal,colorSpecs,colorSpecs0);
            % We do something convoluted here as handles.sPropsMrkr0 might have
            % properties that are not visible/editable in the UI
            tfmkrchanged = ~arrayfun(@(x,y)isequaln(x,structoverlay(x,y)),...
              handles.sPropsMrkr0,sPropsMrkr);
            tfskelchanged = ~arrayfun(@(x,y)isequaln(x,structoverlay(x,y)),...
              handles.sPropsSkel0,sPropsSkel);
            
            sPropsTraj = [];
            if handles.tfTrajControlsShown
              sPropsTraj = TrajControlsGet(app, handles);
            end
            
            %~arrayfun(@isequaln,sPropsMrkr,handles.sPropsMrkr0);
            handles.saved = struct(...
              'colorSpecs',colorSpecs(tfclrchanged),...
              'markerSpecs',sPropsMrkr(tfmkrchanged),...
              'skeletonSpecs',sPropsSkel(tfskelchanged),...
              'trajSpecs',sPropsTraj ...
              );
        end
        
        function s = SkelControlsGet(app, handles)
            % Label and Prediction skeletons share a line width but have
            % independently-editable colors.
            lineWidth = 2^handles.sldSkeletonLineWidth.Value;
            s0 = struct();
            s0.Color = handles.pbSkeletonColor.BackgroundColor;
            s0.LineWidth = lineWidth;
            s1 = struct();
            s1.Color = handles.pbSkeletonColorPred.BackgroundColor;
            s1.LineWidth = lineWidth;
            s = struct(...
              'landmarkSetType',num2cell(enumeration('LandmarkSetType')),...
              'SkeletonProps',{s0;s1});
        end

        function SkelControlsSet(app, handles, s)
            %%%%%%%%%%%%
            % SKELETON
            %%%%%%%%%%%%

            sLbl = s(1).SkeletonProps;
            sPred = s(2).SkeletonProps;
            handles.pbSkeletonColor.BackgroundColor = sLbl.Color;
            handles.pbSkeletonColorPred.BackgroundColor = sPred.Color;
            handles.sldSkeletonLineWidth.Value = log2(sLbl.LineWidth);
        end
        
        function sPropsTraj = TrajControlsGet(app, handles)
            sPropsTraj.TrajColor = handles.pbTrajColor.BackgroundColor;
            sPropsTraj.TrajLineWidth = 2^handles.sldTrajLineWidth.Value;
            handles.txTrajLineWidth.String = sprintf('%.1f', sPropsTraj.TrajLineWidth);
            fs = str2double(handles.editTrajFontSize.String);
            if ~isnan(fs) && fs > 0
              sPropsTraj.TrxIDLblFontSize = fs;
            end
        end
        
        function TrajControlsSet(app, handles, prefs)
            if isnumeric(prefs.TrajColor)
              handles.pbTrajColor.BackgroundColor = prefs.TrajColor(1,:);
            end
            handles.sldTrajLineWidth.Value = log2(max(prefs.TrajLineWidth, 0.5));
            handles.txTrajLineWidth.String = sprintf('%.1f', prefs.TrajLineWidth);
            handles.editTrajFontSize.String = num2str(prefs.TrxIDLblFontSize);
        end
        
        function UpdateColorMode(app, handles)
          return;
            % enable/disable based on radiobutton setting
            % v = get(handles.radiobutton_manual,'Value');
            % hcm = handles.uipanel_colormap.Enable; %findobj(handles.uipanel_colormap,'-property','Enable');
            % hm = handles.uipanel_manual.Enable';
            % if v == 1,
            %   set(hcm,'Enable','off');
            %   set(hm,'Enable','on');
            % else
            %   set(hcm,'Enable','on');
            %   set(hm,'Enable','off');
            % end
        end
        
        function figure_landmarkcolors_CloseRequestFcn(app, hObject, eventdata)
            uiresume(hObject);
        end
        
        function handles = initColorsPane(app, handles)
            pum = handles.pumShowing;
            pum.String = { ...
              'Labels'
              'Predictions'
              };
            pum.Value = 1;
            
            % Colormap pulldown options
            pum = handles.popupmenu_colormap;
            cmapnames = LandmarkColorSpec.CMAPNAMES;
            ncmapnames = numel(cmapnames);
            cmapname2idx = cell2struct(num2cell(1:ncmapnames),cmapnames,2);
            set(pum,'String',LandmarkColorSpec.CMAPNAMES,'Value',1,'UserData',cmapname2idx);
            
            handles.colormapim = imagesc(1:handles.nlandmarks,'Parent',handles.axes_colormap);
            axis(handles.axes_colormap,'off');
            
            % create buttons
            w = 1/handles.nlandmarks;
            borderfrac = .05;
            for i = 1:handles.nlandmarks,
              handles.hbuttons(i) = uicontrol('style','pushbutton',...
                'ForegroundColor','k',...
                'BackgroundColor',[0.5 0.5 0.5],...
                'Units','normalized',...
                'Position',[w*borderfrac+w*(i-1),borderfrac,w*(1-2*borderfrac),1-2*borderfrac],...
                'Parent',handles.uipanel_manual,...
                'Callback',@(hObject,eventdata)LandmarkColors('pushbutton_manual_Callback',hObject,eventdata,guidata(hObject),i),...
                'Tag',sprintf('pushbutton_manual_%d',i),...
                'String',num2str(i));
            end
        end
        
        function handles = initTrajPane(app, handles, lObj)
            % Inserts a Trajectory panel between the existing content and the Done/Cancel
            % buttons by expanding the figure downward, shifting all content up, and
            % repositioning Done/Cancel at the new bottom.
            
            prefs = lObj.projPrefs.Trx;
            hFig = handles.figure_landmarkcolors;
            panH = 100; % height added for trajectory panel (panel itself is ~45px, rest is margin)
            btnH = 23; % approximate height of Done/Cancel buttons
            btnMargin = 8;
            
            hFig.Units = 'pixels';
            origPos = hFig.Position;
            
            % Capture Done/Cancel button handles and their x positions before any shifts
            hDone   = handles.pbDone; %findobj(hFig, 'Tag', 'pbDone');
            hDone.Units = 'pixels';
            
            % Expand figure downward
            hFig.Position = [origPos(1) origPos(2)-panH origPos(3) origPos(4)+panH];
            
            % Shift all children UP so they retain their screen positions
            for hChild = hFig.Children(:)'
              try
                hChild.Units = 'pixels';
                p = hChild.Position;
                hChild.Position = [p(1) p(2)+panH p(3) p(4)];
              catch
              end
            end
            
            % Move Done and Cancel buttons back to the new bottom
            if isvalid(hDone)
              p = hDone.Position;
              hDone.Position = [p(1) btnMargin p(3) p(4)];
            end
            
            
            % Place trajectory panel just above the buttons
            pnlY = btnMargin + btnH + 26;
            pnlH = panH - 14;
            pnlW = origPos(3) - 10;
            pnl = uipanel(hFig, 'Title', 'Trajectory', 'Units', 'pixels', ...
              'Position', [5 pnlY pnlW pnlH], 'Tag', 'pnlTraj','FontName','Helvetica','FontAngle','italic','FontSize',14);
            
            if isnumeric(prefs.TrajColor)
              trajClr0 = prefs.TrajColor(1,:);
            else
              trajClr0 = [1 1 0];
            end
            
            % Single-row layout: Color | LineWidth slider | FontSize
            rowY = 14;
            rowH = 20;
            x = 5;
            
            uicontrol(pnl, 'Style', 'text', 'String', 'Color:', ...
              'Units', 'pixels', 'Position', [x rowY 38 rowH], 'HorizontalAlignment', 'right');
            x = x + 42;
            handles.pbTrajColor = uicontrol(pnl, 'Style', 'pushbutton', ...
              'BackgroundColor', trajClr0, 'String', '', ...
              'Units', 'pixels', 'Position', [x rowY 50 rowH], 'Tag', 'pbTrajColor', ...
              'Callback', @(h,e)pbTrajColor_Callback(app, h, e, guidata(h)));
            x = x + 58;
            
            uicontrol(pnl, 'Style', 'text', 'String', 'Line width:', ...
              'Units', 'pixels', 'Position', [x rowY 65 rowH], 'HorizontalAlignment', 'right');
            x = x + 68;
            sldW = pnlW - x - 150;
            handles.sldTrajLineWidth = uicontrol(pnl, 'Style', 'slider', ...
              'Min', log2(0.5), 'Max', log2(16), ...
              'Value', log2(max(prefs.TrajLineWidth, 0.5)), ...
              'Units', 'pixels', 'Position', [x rowY sldW rowH], 'Tag', 'sldTrajLineWidth');
            x = x + sldW + 3;
            handles.txTrajLineWidth = uicontrol(pnl, 'Style', 'text', ...
              'String', sprintf('%.1f', prefs.TrajLineWidth), ...
              'Units', 'pixels', 'Position', [x rowY 32 rowH], 'Tag', 'txTrajLineWidth');
            x = x + 36;
            
            uicontrol(pnl, 'Style', 'text', 'String', 'Font size:', ...
              'Units', 'pixels', 'Position', [x rowY 58 rowH], 'HorizontalAlignment', 'right');
            x = x + 61;
            handles.editTrajFontSize = uicontrol(pnl, 'Style', 'edit', ...
              'String', num2str(prefs.TrxIDLblFontSize), ...
              'Units', 'pixels', 'Position', [x rowY 40 rowH], 'Tag', 'editTrajFontSize', ...
              'Callback', @(h,e)pbApply_Callback(app, handles.output, [], guidata(h)));
            
            addlistener(handles.sldTrajLineWidth, 'ContinuousValueChange', ...
              @(s,e)sldTrajLineWidth_Callback(app, s, [], guidata(s)));
            
            handles.sPropsTraj0 = struct('TrajColor', trajClr0, ...
              'TrajLineWidth', prefs.TrajLineWidth, 'TrxIDLblFontSize', prefs.TrxIDLblFontSize);
        end
        
        function pbApply_Callback(app, hObject, eventdata, handles)
            handles = SaveState(app, handles);
            saved = handles.saved;
            handles.applyCbkFcn(saved.colorSpecs, saved.markerSpecs, saved.skeletonSpecs, saved.trajSpecs);
            guidata(hObject, handles);
            % uiresume(handles.figure_landmarkcolors)
        end
        
        function pbCancel_Callback(app, hObject, eventdata, handles)
            uiresume(handles.figure_landmarkcolors);
        end
        
        function pbTrajColor_Callback(app, hObject, eventdata, handles)
            c0 = handles.pbTrajColor.BackgroundColor;
            newclr = uisetcolor(c0, 'Trajectory color');
            if numel(newclr) == 3
              handles.pbTrajColor.BackgroundColor = newclr;
            end
            pbApply_Callback(app, handles.output, [], handles);
        end
        
        function pushbutton_manual_Callback(app, hObject, eventdata, handles, landmarki)
            fprintf('Landmark %d\n',landmarki);
            cs = handles.colorSpecs;
            iCS = handles.pumShowing.Value;
            cs = cs(iCS);
            clr = uisetcolor(cs.colors(landmarki,:),sprintf('Landmark %d color',landmarki));
            cs.setColorManual(landmarki,clr);
            updateColorsPane(app, handles);
            pbApply_Callback(app, handles.output,[],handles);
        end
        
        function sldTrajLineWidth_Callback(app, hObject, eventdata, handles)
            pbApply_Callback(app, handles.output, [], handles);
        end
        
        function tblCellEditCallback(app, src, evt)
            handles = guidata(src);
            pbApply_Callback(app, handles.output,[],handles);
        end
        
        function updateColorsPane(app, handles)
            % Update colors pane uicontrols per current colorSpecs
            
            cs = handles.colorSpecs;
            iCS = handles.pumShowing.Value;
            cs = cs(iCS);
            
            % mode
            btnGrp = handles.radiobutton_manual.Parent;
            if cs.tfmanual
              btnGrp.SelectedObject = app.radiobutton_manual;
            else
              btnGrp.SelectedObject = app.radiobutton_colormap;
            end
            
            UpdateColorMode(app, handles);
            
            if cs.tfmanual
              % manual button colors
              for i = 1:handles.nlandmarks,
                set(handles.hbuttons(i),'BackgroundColor',cs.colors(i,:));
              end
            else
              % colormapname
              cmapname = cs.colormapname;
              pumcmap = handles.popupmenu_colormap;
              cmapidx = pumcmap.UserData.(cmapname);
              pumcmap.Value = cmapidx;
            
              % colormap
              colormap(handles.axes_colormap,cs.colormap);
            
              % brightness
              set(handles.edit_brightness,'String',num2str(cs.brightness));
              set(handles.slider_brightness,'Value',cs.brightness);
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.tblProps.BackgroundColor = [1 1 1;0.94 0.94 0.94];
            app.tblProps.ColumnFormat = {[] [] [] [] [] [] []};
        end
    
        function skeletonLineWidthUpdate(app,event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>            
            pbApply_Callback(app, handles.output,[],handles);
        end
    end
    

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function LandmarkColors_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure_landmarkcolors, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % [tfchanges,savedinfo] = LandmarkColors(lObj,cbk)
            
            % cbk sig: cbk(colorSpecs,markerSpecs,skelSpecs)
            %   colorSpecs: array of LandmarkColorSpec objs (could be nonscalar)
            %   markerSpecs: [3] struct array nested Marker/TextProps etc
            %   skelSpecs: [3] struct array
            
            handles.output = hObject;
            set(hObject,'MenuBar','None');
            
            hObject.CloseRequestFcn = @app.figure_landmarkcolors_CloseRequestFcn;
            
            controller = varargin{1};
            lObj = varargin{2};
            handles.nlandmarks = lObj.nPhysPoints;
            handles.applyCbkFcn = varargin{3}; % sig:

            mainFigurePosition = controller.mainFigurePixelPosition() ;
            centerOnOtherFigureGivenPositionBang(hObject, mainFigurePosition) ;
            
            
            % Marker State
            % This is stored only in the table. Get and Set using Set/GetMarker*.
            lsetTypesCell = num2cell(enumeration('LandmarkSetType'));
            lsetTypesCellCount = numel(lsetTypesCell) ;
            ppiAll = {lObj.labelPointsPlotInfo; lObj.predPointsPlotInfo};
            FLDS = {'MarkerProps' 'TextProps' 'TextOffset'};
            sPropsMrkr = cellfun(@(x)structrestrictflds(x,FLDS),ppiAll);
            [sPropsMrkr.landmarkSetType] = deal(lsetTypesCell{:});
            % Color State
            % This state is maintained in handles.colorSpecs. What is shown in the
            % Colors pane is per pumShowing.
            handles.colorSpecs = cellfun(@(x1,x2,x3)LandmarkColorSpec(x1,x2,x3),...
              lsetTypesCell,...
              repmat({lObj.nPhysPoints},lsetTypesCellCount,1),ppiAll);
            % Skel State
            FLDS = {'SkeletonProps'};
            sPropsSkel = cellfun(@(x)structrestrictflds(x,FLDS),ppiAll);
            [sPropsSkel.landmarkSetType] = deal(lsetTypesCell{:});
            
            handles.sPropsMrkr0 = sPropsMrkr;
            handles.colorSpecs0 = handles.colorSpecs.copy();
            handles.sPropsSkel0 = sPropsSkel;
            
            handles = initColorsPane(app, handles);
            updateColorsPane(app, handles);
            
            MARKERS = 'o+*.xsd^v><ph';
            MARKERS = num2cell(MARKERS);
            tbl = handles.tblProps;
            tbl.ColumnFormat{1} = MARKERS;
            strcmp(tbl.ColumnName{6},'Label Font Angle');
            tbl.ColumnFormat{6} = {'normal' 'italic'};
            ncol = numel(tbl.ColumnFormat);
            set(tbl,'Data',cell(3,ncol),'RowName',{'Label' 'Prediction'});
            
            MarkerControlsSet(app, handles,sPropsMrkr);
            
            handles.sldSkeletonLineWidth.Min = -1; %log scale
            handles.sldSkeletonLineWidth.Max = 5;
%            handles.sldSkeletonLineWidth.Callback = @sldSkeletonLineWidth_Callback;
            SkelControlsSet(app, handles,sPropsSkel);
            
            handles.tfTrajControlsShown = false;
            if lObj.hasTrx || lObj.maIsMA
              handles = initTrajPane(app, handles, lObj);
              handles.tfTrajControlsShown = true;
            end
            
            set(handles.figure_landmarkcolors,'Name','Landmark Cosmetics');
            handles.saved = [];
            guidata(hObject, handles);
            
            handles.tblProps.CellEditCallback = @app.tblCellEditCallback;
            
            % UIWAIT makes LandmarkColors wait for user response (see UIRESUME)
            uiwait(handles.figure_landmarkcolors);
            delete(app)


    end

    % Callback function
    function edit_brightness_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            v = str2double(get(hObject,'String'));
            cs = handles.colorSpecs;
            iCS = handles.pumShowing.Value;
            cs = cs(iCS);
            
            if isnan(v) || v < 0 || v > 1,
              warndlg('Brightness must be a number between 0 and 1','Error setting brightness','modal');
              set(hObject,'String',num2str(cs.brightness));
            else
              cs.setBrightness(v);
              %set(handles.slider_brightness,'Value',v);
              updateColorsPane(app, handles);
              pbApply_Callback(app, handles.output,[],handles);
            end
    end

    % Button pushed function: pbDone
    function pbDone_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles = SaveState(app, handles);
            guidata(hObject,handles);
            uiresume(handles.figure_landmarkcolors);
    end

    % Button pushed function: pbSkeletonColor
    function pbSkeletonColor_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            c0 = handles.pbSkeletonColor.BackgroundColor;
            handles.pbSkeletonColor.BackgroundColor = uisetcolor(c0);
            pbApply_Callback(app, handles.output,[],handles);
    end

    % Button pushed function: pbSkeletonColorPred
    function pbSkeletonColorPred_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            c0 = handles.pbSkeletonColorPred.BackgroundColor;
            handles.pbSkeletonColorPred.BackgroundColor = uisetcolor(c0);
            pbApply_Callback(app, handles.output,[],handles);
    end

    % Callback function
    function popupmenu_colormap_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            contents = cellstr(get(hObject,'String'));
            cmapname = contents{get(hObject,'Value')};
            
            cs = handles.colorSpecs;
            iCS = handles.pumShowing.Value;
            cs = cs(iCS);
            cs.setColormapName(cmapname);
            updateColorsPane(app, handles);
            pbApply_Callback(app, handles.output,[],handles);
    end

    % Callback function
    function pumShowing_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            updateColorsPane(app, handles);
    end

    % Value changed function: sldSkeletonLineWidth
    function sldSkeletonLineWidth_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
        skeletonLineWidthUpdate(app,event);
    end

    % Callback function
    function slider_brightness_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            v = get(hObject,'Value');
            cs = handles.colorSpecs;
            iCS = handles.pumShowing.Value;
            cs = cs(iCS);
            cs.setBrightness(v);
            updateColorsPane(app, handles);
            pbApply_Callback(app, handles.output,[],handles);
    end

    % Callback function
    function uibuttongroup1SelectionChanged(app, event)
      cs = app.colorSpecs;
      iCS = app.pumShowing.Value;
      cs = cs(iCS);
      if event.NewValue == app.radiobutton_manual
          cs.setManualColorsToColormap();
          cs.setTFManual(true);
      else  % radiobutton_colormap
          cs.setTFManual(false);
      end
      app.updateColorsPane();
    end

    % Close request function: figure_landmarkcolors
    function figure_landmarkcolorsCloseRequest(app, event)
      uiresume(app.UIFigure);
      delete(app)
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure_landmarkcolors = uifigure('Visible', 'off');
      app.figure_landmarkcolors.Name = 'LandmarkColors';
      app.figure_landmarkcolors.CloseRequestFcn = createCallbackFcn(app, @figure_landmarkcolorsCloseRequest, true);
      app.figure_landmarkcolors.Tag = 'figure_landmarkcolors';
      app.pbDone = uibutton(app.figure_landmarkcolors, 'push');
      app.pbDone.ButtonPushedFcn = createCallbackFcn(app, @pbDone_Callback, true);
      app.pbDone.Tag = 'pbDone';
      app.pbDone.Text = 'Done';
      app.pnlTab = uipanel(app.figure_landmarkcolors);
      app.pnlTab.Tag = 'pnlTab';
      app.uibuttongroup1 = uibuttongroup(app.pnlTab);
      app.uibuttongroup1.Title = 'Colors';
      app.uibuttongroup1.Tag = 'uibuttongroup1';
      app.radiobutton_colormap = uiradiobutton(app.uibuttongroup1);
      app.radiobutton_colormap.Tag = 'radiobutton_colormap';
      app.radiobutton_colormap.Text = 'Colormap';
      app.radiobutton_colormap.Value = true;
      app.uipanel_manual = uipanel(app.uibuttongroup1);
      app.uipanel_manual.Tag = 'uipanel_manual';
      app.uipanel_colormap = uipanel(app.uibuttongroup1);
      app.uipanel_colormap.Tag = 'uipanel_colormap';
      app.axes_colormap = uiaxes(app.uipanel_colormap);
      app.axes_colormap.Tag = 'axes_colormap';
      colormap(app.axes_colormap, 'parula')
      app.popupmenu_colormap = uidropdown(app.uipanel_colormap);
      app.popupmenu_colormap.Items = {'jet', 'parula', 'hsv', 'lines', 'autumn', 'spring', 'winter', 'summer', 'hot', 'cool'};
      app.popupmenu_colormap.Tag = 'popupmenu_colormap';
      app.popupmenu_colormap.Value = 'jet';
      app.slider_brightness = uislider(app.uipanel_colormap);
      app.slider_brightness.Limits = [0 1];
      app.slider_brightness.MajorTicks = [];
      app.slider_brightness.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
      app.slider_brightness.Step = 0.01;
      app.slider_brightness.Tag = 'slider_brightness';
      app.slider_brightness.Value = 0.5;
      app.edit_brightness = uieditfield(app.uipanel_colormap, 'text');
      app.edit_brightness.Tag = 'edit_brightness';
      app.edit_brightness.Value = '0.50';
      app.text2 = uilabel(app.uipanel_colormap);
      app.text2.Tag = 'text2';
      app.text2.Text = 'Light';
      app.text3 = uilabel(app.uipanel_colormap);
      app.text3.Tag = 'text3';
      app.text3.Text = 'Dark';
      app.radiobutton_manual = uiradiobutton(app.uibuttongroup1);
      app.radiobutton_manual.Tag = 'radiobutton_manual';
      app.radiobutton_manual.Text = 'Manual';
      app.pumShowing = uidropdown(app.uibuttongroup1);
      app.pumShowing.Items = {'Labels', 'Predictions', 'Imported'};
      app.pumShowing.Tag = 'pumShowing';
      app.pumShowing.Value = 'Labels';
      app.text12 = uilabel(app.uibuttongroup1);
      app.text12.Tag = 'text12';
      app.text12.Text = 'Showing colors for:';
      app.cbApplyAll = uicheckbox(app.uibuttongroup1);
      app.cbApplyAll.Tag = 'cbApplyAll';
      app.cbApplyAll.Text = '';
      app.text19 = uilabel(app.uibuttongroup1);
      app.text19.Tag = 'text19';
      app.text19.Text = 'Apply colors to labels, predictions, imported';
      app.pnlMarkersLabels = uipanel(app.pnlTab);
      app.pnlMarkersLabels.Title = 'Markers and Labels';
      app.pnlMarkersLabels.Tag = 'pnlMarkersLabels';
      app.tblProps = uitable(app.pnlMarkersLabels);
      app.tblProps.ColumnName = {'Marker'; 'MarkerSize'; 'LineWidth'; 'Show Text Label'; 'Label Font Size'; 'Label Font Angle'; 'Label Offset'};
      app.tblProps.RowName = {'Label'; 'Prediction'; 'Imported'; ''};
      app.tblProps.ColumnEditable = [true true true true true true true];
      app.tblProps.Tag = 'tblProps';
      app.uipanel6 = uipanel(app.figure_landmarkcolors);
      app.uipanel6.Title = 'Skeleton';
      app.uipanel6.Tag = 'uipanel6';
      app.sldSkeletonLineWidth = uislider(app.uipanel6);
      app.sldSkeletonLineWidth.Limits = [0 1];
      app.sldSkeletonLineWidth.MajorTicks = [];
      app.sldSkeletonLineWidth.ValueChangedFcn = createCallbackFcn(app, @sldSkeletonLineWidth_Callback, true);
      app.sldSkeletonLineWidth.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
      app.sldSkeletonLineWidth.Step = 0.01;
      app.sldSkeletonLineWidth.Tag = 'sldSkeletonLineWidth';
      app.text21 = uilabel(app.uipanel6);
      app.text21.Tag = 'text21';
      app.text21.Text = 'Thickness:';
      app.pbSkeletonColor = uibutton(app.uipanel6, 'push');
      app.pbSkeletonColor.ButtonPushedFcn = createCallbackFcn(app, @pbSkeletonColor_Callback, true);
      app.pbSkeletonColor.Tag = 'pbSkeletonColor';
      app.pbSkeletonColor.Text = '';
      app.text22 = uilabel(app.uipanel6);
      app.text22.Tag = 'text22';
      app.text22.Text = 'Label:';
      app.pbSkeletonColorPred = uibutton(app.uipanel6, 'push');
      app.pbSkeletonColorPred.ButtonPushedFcn = createCallbackFcn(app, @pbSkeletonColorPred_Callback, true);
      app.pbSkeletonColorPred.Tag = 'pbSkeletonColorPred';
      app.pbSkeletonColorPred.Text = '';
      app.text23 = uilabel(app.uipanel6);
      app.text23.Tag = 'text23';
      app.text23.Text = 'Pred:';
      % Show the figure after all components are created
      app.figure_landmarkcolors.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = LandmarkColors_App(varargin)

      runningApp = getRunningApp(app);

      % Check for running singleton app
      if isempty(runningApp)

        % Create UIFigure and components
        createComponents(app)

        % Register the app with App Designer
        registerApp(app, app.figure_landmarkcolors)

        % Execute the startup function
        runStartupFcn(app, @(app)LandmarkColors_OpeningFcn(app, varargin{:}))
      else

        % Focus the running singleton app
        figure(runningApp.figure_landmarkcolors)

        app = runningApp;
      end

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure_landmarkcolors)
    end
  end
end