classdef MFTSetSelect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        MovieButtonGroup          matlab.ui.container.ButtonGroup
        CurrentMovieButton        matlab.ui.control.RadioButton
        SelectedMoviesButton      matlab.ui.control.RadioButton
        AllMoviesButton           matlab.ui.control.RadioButton
        FramesPanel               matlab.ui.container.Panel
        ButtonGroup               matlab.ui.container.ButtonGroup
        AllFramesButton           matlab.ui.control.RadioButton
        LabeledFramesButton       matlab.ui.control.RadioButton
        SelectedFramesButton      matlab.ui.control.RadioButton
        NearCurrentFrameButton    matlab.ui.control.RadioButton
        RadiusEditFieldLabel      matlab.ui.control.Label
        RadiusEditField           matlab.ui.control.NumericEditField
        DecimationEditFieldLabel  matlab.ui.control.Label
        DecimationEditField       matlab.ui.control.NumericEditField
        TargetsButtonGroup        matlab.ui.container.ButtonGroup
        CurrentTargetButton       matlab.ui.control.RadioButton
        AllTargetsButton          matlab.ui.control.RadioButton
        ApplyButton               matlab.ui.control.Button
        CancelButton              matlab.ui.control.Button
    end


    methods (Access = private)
    
        function mftset = compileMFTSet(app)
            switch app.MovieButtonGroup.SelectedObject
                case app.CurrentMovieButton
                    mset = MovieIndexSetVariable.CurrMov;
                case app.SelectedMoviesButton
                    mset = MovieIndexSetVariable.SelMov;
                case app.AllMoviesButton
                    mset = MovieIndexSetVariable.AllMov;
                otherwise
                    assert(false);
            end
            
            switch app.TargetsButtonGroup.SelectedObject
                case app.CurrentTargetButton
                    tset = TargetSetVariable.CurrTgt;
                case app.AllTargetsButton
                    tset = TargetSetVariable.AllTgts;
                otherwise
                    assert(false);
            end

            radius = nan;
            switch app.ButtonGroup.SelectedObject
                case app.AllFramesButton
                    fset = FrameSetVariable.AllFrm;
                case app.LabeledFramesButton
                    fset = FrameSetVariable.LabeledFrm;
                case app.SelectedFramesButton
                    fset = FrameSetVariable.SelFrm;
                case app.NearCurrentFrameButton
                    fset = FrameSetVariable.WithinCurrFrm;
                    radius = app.RadiusEditField.Value;
                otherwise
                    assert(false);
            end
            
            decimation = FrameDecimationFixed(app.DecimationEditField.Value);
            
            mftset = MFTSet(mset,fset,decimation,tset);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Selection changed function: ButtonGroup
        function FramesButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            mftset = app.compileMFTSet();
            setappdata(0,'apt_mftsetselect_success',true);
            setappdata(0,'apt_mftsetselect_mftset',mftset);
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            setappdata(0,'apt_mftsetselect_success',false);
            setappdata(0,'apt_mftsetselect_mftset',[]);
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MovieButtonGroup = uibuttongroup(app.UIFigure);
            app.MovieButtonGroup.Title = 'Movie';
            app.CurrentMovieButton = uiradiobutton(app.MovieButtonGroup);
            app.CurrentMovieButton.Text = 'Current Movie';
            app.CurrentMovieButton.Value = true;
            app.SelectedMoviesButton = uiradiobutton(app.MovieButtonGroup);
            app.SelectedMoviesButton.Text = 'Selected Movies';
            app.AllMoviesButton = uiradiobutton(app.MovieButtonGroup);
            app.AllMoviesButton.Text = 'All Movies';
            app.FramesPanel = uipanel(app.UIFigure);
            app.FramesPanel.Title = 'Frames';
            app.ButtonGroup = uibuttongroup(app.FramesPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FramesButtonGroupSelectionChanged, true);
            app.AllFramesButton = uiradiobutton(app.ButtonGroup);
            app.AllFramesButton.Text = 'All Frames';
            app.AllFramesButton.Value = true;
            app.LabeledFramesButton = uiradiobutton(app.ButtonGroup);
            app.LabeledFramesButton.Text = 'Labeled Frames';
            app.SelectedFramesButton = uiradiobutton(app.ButtonGroup);
            app.SelectedFramesButton.Text = 'Selected Frames';
            app.NearCurrentFrameButton = uiradiobutton(app.ButtonGroup);
            app.NearCurrentFrameButton.Text = 'Near Current Frame';
            app.RadiusEditFieldLabel = uilabel(app.ButtonGroup);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.DecimationEditFieldLabel = uilabel(app.FramesPanel);
            app.DecimationEditFieldLabel.Text = 'Decimation';
            app.DecimationEditField = uieditfield(app.FramesPanel, 'numeric');
            app.DecimationEditField.Limits = [0 Inf];
            app.DecimationEditField.ValueDisplayFormat = '%11.5g';
            app.TargetsButtonGroup = uibuttongroup(app.UIFigure);
            app.TargetsButtonGroup.Title = 'Targets';
            app.CurrentTargetButton = uiradiobutton(app.TargetsButtonGroup);
            app.CurrentTargetButton.Text = 'Current Target';
            app.CurrentTargetButton.Value = true;
            app.AllTargetsButton = uiradiobutton(app.TargetsButtonGroup);
            app.AllTargetsButton.Text = 'All Targets';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MFTSetSelect

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end