classdef keypoints < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KeypointsUIFigure              matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        AddEdgeButton                  matlab.ui.control.Button
        RemoveEdgeButton               matlab.ui.control.Button
        DoneButton                     matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        SpecfyHeadLandmarkSwitchLabel  matlab.ui.control.Label
        SpecfyHeadLandmarkSwitch       matlab.ui.control.Switch
        UITable                        matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        lObj
        pts
        edges
        
        hpts
        hEdgeSelected
        hedges
        hIm
        htxt
        
        iSelected
        
        selectedColor
        selectedMarker
        selectedMarkerSize
        selectedLineWidth
        unselectedColor
        unselectedMarker
        unselectedMarkerSize        
        unselectedLineWidth
    end
    
    methods (Access = private, Static)
      
      function [im,pts,imagescArgs,labelCM,axesProps,txtOffset] = ...
          parseLabelerArgs(lObj)
        
        freezeInfo = lObj.prevAxesModeInfo;
        imagescArgs = {'XData',freezeInfo.xdata,'YData',freezeInfo.ydata};
        im = lObj.prevAxesModeInfo.im;
        axcProps = freezeInfo.axes_curr;
        axesProps = {};
        for prop=fieldnames(axcProps)',
          axesProps(end+1:end+2) = {prop{1},axcProps.(prop{1})};
        end
        if freezeInfo.isrotated,
          axesProps(end+1:end+2) = {'CameraUpVectorMode','auto'};
        end
        isrotated = freezeInfo.isrotated;
        
        iMov = freezeInfo.iMov;
        frm = freezeInfo.frm;
        iTgt = freezeInfo.iTgt;
        
        lpos = lObj.labelsGTaware;
        s = lpos{iMov};
        [~,p,~] = Labels.isLabeledFT(s,frm,iTgt);
        pts = reshape(p,numel(p)/2,2);
        %pts = lpos{iMov}(:,:,frm,iTgt);
        if isrotated,
          pts = [pts,ones(size(pts,1),1)]*freezeInfo.A;
          pts = pts(:,1:2);
        end
        labelCM = lObj.LabelPointColors;
        txtOffset = lObj.labelPointsPlotInfo.TextOffset;
        
      end    
     
    end
    
    methods (Access=private)
      function updateTable(app,head)
        % Updates table state assuming app.SpecfyHeadLandmarkSwitch is set
        % head: either [] if SpecifyHead is off, otherwise head pt
      
        ht = app.UITable;
        
        npts = size(app.pts,1);
        names = arrayfun(@num2str,(1:npts)','uni',0);
        
        tfheadtail = strcmp(app.SpecfyHeadLandmarkSwitch.Value,'On');
        if tfheadtail
          hvec = false(npts,1);
          hvec(head) = true;
          dat = [names(:) num2cell(hvec)];
          colhdr = {'Name' 'Head'};
        else
          dat = names(:);
          colhdr = {'Name'};
        end
        ncol = size(dat,2);
        ht.RowName = 'numbered';
        ht.Data = dat;
        ht.ColumnName = colhdr;
        ht.ColumnEditable = true(1,ncol);
        ht.ColumnWidth = 'auto';
      end
      function [htEnabled,ptNames,ht] = getTableState(app)
        htEnabled = strcmp(app.SpecfyHeadLandmarkSwitch.Value,'On');
        ht = app.UITable;
        dat = ht.Data;
        ptNames = dat(:,1);
        if htEnabled
          ht = cell2mat(dat(:,2));
          ht = find(ht);
        else
          ht = [];
        end
      end
      function ptClicked(app,h,e)
        iClicked = get(h,'UserData');
        
        iSelected = app.iSelected;
        hpts = app.hpts;
        pts = app.pts;
        hEdgeSelected = app.hEdgeSelected;
        
        j = find(iSelected==iClicked);
        if isempty(j),
          iSelect = iClicked;
          iUnselect = [];
          iSelected(end+1) = iClicked;
          if numel(iSelected) > 2,
            iUnselect = iSelected(1);
            iSelected = iSelected(end-1:end);
          end
        else
          iSelected(j) = [];
          iSelect = [];
          iUnselect = iClicked;
        end
        if ~isempty(iSelect),
          set(hpts(iSelect),'Marker',app.selectedMarker,'MarkerSize',app.selectedMarkerSize);
        end
        if ~isempty(iUnselect),
          set(hpts(iUnselect),'Marker',app.unselectedMarker,'MarkerSize',app.unselectedMarkerSize);
        end
        if numel(iSelected) >= 2,
          isSelected = ismember(sort(iSelected),app.edges,'rows');
          set(hEdgeSelected,'XData',pts(iSelected,1),'YData',pts(iSelected,2));
          if isSelected,
            set(hEdgeSelected,'Color',app.selectedColor);
          else
            set(hEdgeSelected,'Color',app.unselectedColor);
          end
        else
          set(hEdgeSelected,'XData',nan(2,1),'YData',nan(2,1));
        end
        
        app.iSelected = iSelected;       
      end
      function edgeClicked(app,h,e)
        edge = get(h,'UserData');
        
        iSelected = app.iSelected;
        hpts = app.hpts;
        hEdgeSelected = app.hEdgeSelected;        
        
        if ~isempty(iSelected),
          set(hpts(iSelected),'Marker',app.unselectedMarker,'MarkerSize',app.unselectedMarkerSize);
          set(app.hEdgeSelected,'XData',nan(2,1),'YData',nan(2,1));
        end
        if numel(iSelected==2) && all(edge == sort(iSelected)),
          iSelected = [];
        else
          iSelected = edge;
          set(hpts(iSelected),'Marker',app.selectedMarker,'MarkerSize',app.selectedMarkerSize);
          set(hEdgeSelected,'XData',app.pts(iSelected,1),'YData',app.pts(iSelected,2));
          isSelected = ismember(sort(iSelected),app.edges,'rows');
          if isSelected,
            set(hEdgeSelected,'Color',app.selectedColor);
          else
            % this should never happen
            set(hEdgeSelected,'Color',app.unselectedColor);
          end
        end
        
        app.iSelected = iSelected;
      end
      function moveEdgesToBack(app)
        
        hAx = app.UIAxes;        
        hchil = get(hAx,'Children');
        hchil1 = [app.hIm;app.htxt(:);app.hedges(:);app.hEdgeSelected;app.hpts(:)];
        hleft = hchil;
        hleft(ismember(hleft,hchil1)) = [];
        set(hAx,'Children',flipud([hleft;hchil1]));
      end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
          [lObj,edges,plotptsArgs,textArgs,...
           txtOffset,selectedMarkerSize,unselectedMarkerSize,...
           selectedMarker,unselectedMarker,...
           unselectedColor,selectedColor,...
           unselectedLineWidth,selectedLineWidth] = myparse(varargin,...
            'lObj',[],...
            'edges',[], ...
            'plotptsArgs',{},...
            'textArgs',{}, ...
             'txtOffset',2,...
             'selectedMarkerSize',12,...
             'unselectedMarkerSize',8,...
             'selectedMarker','o','unselectedMarker','+',...
             'unselectedColor',[.5,.5,.5],'selectedColor',[.8,0,.8],...
             'unselectedLineWidth',2,'selectedLineWidth',4 ...
           );
          
           if ~isempty(lObj)
               centerOnParentFigure(app.KeypointsUIFigure,lObj.hFig,'setParentFixUnitsPx',true);
           else
               centerfig(app.KeypointsUIFigure);
           end
           
          [im,pts,imagescArgs,labelCM,axesProps,txtOffset] = app.parseLabelerArgs(lObj);
          edges = lObj.skeletonEdges;
          if isempty(edges),
            edges = zeros(0,2);
          end
          
          hAx = app.UIAxes;
          hold(hAx,'off');
          hIm = imagesc(im,'Parent',hAx,imagescArgs{:});
          hold(hAx,'on');
          colormap(hAx,'gray');
          axis(hAx,'image','off');
          
          npts = size(pts,1);
          hpts = gobjects(npts,1);
          
          if isempty(labelCM),
            labelCM = jet(npts);
          end
          
          htxt = gobjects(npts,1);
          for i = 1:npts,
            htxt(i) = text(hAx,pts(i,1)+txtOffset,pts(i,2)+txtOffset,num2str(i),'Color',labelCM(i,:),'PickableParts','none',textArgs{:});
          end
          
          hEdgeSelected = plot(hAx,nan(2,1),nan(2,1),'-','Color',unselectedColor,'LineWidth',selectedLineWidth,'PickableParts','none');
          
          for i = 1:npts,
            hpts(i) = plot(hAx,pts(i,1),pts(i,2),unselectedMarker,'Color',labelCM(i,:),'MarkerFaceColor',labelCM(i,:),...
              'UserData',i,'MarkerSize',unselectedMarkerSize,plotptsArgs{:});
            set(hpts(i),'ButtonDownFcn',@(h,e)app.ptClicked(h,e));
          end
          
          hedges = gobjects(1,size(edges,1));
          for i = 1:size(edges,1),
            hedges(i) = plot(hAx,pts(edges(i,:),1),pts(edges(i,:),2),'-','Color',selectedColor,'LineWidth',unselectedLineWidth,...
              'ButtonDownFcn',@(h,e)app.edgeClicked(h,e),'UserData',edges(i,:),'Tag',sprintf('edge%d',i));
          end
          
          if ~isempty(axesProps),
            set(hAx,axesProps{:});
          end
          
          app.lObj = lObj;
          app.pts = pts;
          app.edges = edges;
          
          app.hpts = hpts;
          app.hedges = hedges;
          app.hIm = hIm;
          app.htxt = htxt;
          
          app.hEdgeSelected = hEdgeSelected;
          app.iSelected = [];
          app.selectedColor = selectedColor;
          app.selectedMarker = selectedMarker;
          app.selectedMarkerSize = selectedMarkerSize;
          app.selectedLineWidth = selectedLineWidth;
          app.unselectedColor = unselectedColor;
          app.unselectedMarker = unselectedMarker;
          app.unselectedMarkerSize = unselectedMarkerSize;          
          app.unselectedLineWidth = unselectedLineWidth;
          
          head = app.lObj.skelHead;
          tfheadspecd = ~isempty(head);
          app.SpecfyHeadLandmarkSwitch.Value = onIffCaps(tfheadspecd);
          app.updateTable(head);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.KeypointsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {392, 392};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {448, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: AddEdgeButton
        function AddEdgeButtonPushed(app, event)
          iSelected = app.iSelected;
          edges = app.edges;
          hedges = app.hedges;
          pts = app.pts;
          
          if numel(iSelected) < 2,
            fprintf('No edge selected. Edge must be selected to add.\n');
            return;
          end
          edge = sort(iSelected);
          if ismember(edge,edges,'rows'),
            fprintf('Edge already selected.\n');
            return;
          end
          edges(end+1,:) = edge;
          hedges(end+1) = plot(app.UIAxes,pts(edge,1),pts(edge,2),'-','Color',app.selectedColor,'LineWidth',app.unselectedLineWidth,...
            'ButtonDownFcn',@(h,e)app.edgeClicked(h,e),'UserData',edge,'Tag',sprintf('edge%d',numel(hedges)+1));
          set(app.hEdgeSelected,'Color',app.selectedColor);
          
          app.edges = edges;
          app.hedges = hedges;
          
          app.moveEdgesToBack();
        end

        % Button pushed function: RemoveEdgeButton
        function RemoveEdgeButtonPushed(app, event)
          iSelected = app.iSelected;
          edges = app.edges;
          hedges = app.hedges;
          
          if numel(iSelected) < 2,
            fprintf('No edge selected. Edge must be selected to add.\n');
            return;
          end
          edge = sort(iSelected);
          [~,j] = ismember(edge,edges,'rows');
          if j == 0,
            fprintf('Edge is not included in skeleton, cannot remove.\n');
            return;
          end
          edges(j,:) = [];
          delete(hedges(j));
          hedges = [hedges(1:j-1),hedges(j+1:end)];
          
          set(app.hEdgeSelected,'Color',app.unselectedColor);
          
          app.edges = edges;
          app.hedges = hedges;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.lObj.setSkeletonEdges(app.edges);
            [htEnabled,ptNames,ht] = app.getTableState();
            if htEnabled
                assert(isscalar(ht))
%                 if nnz(ht)>1
%                     warningNoTrace('Using only first head point specification: %d',ht(1));
%                     ht = ht(1);                    
%                 end                
                app.lObj.setSkelHead(ht);
            else
                app.lObj.setSkelHead([]);
            end
            app.lObj.setSkelNames(ptNames);
            %app.lObj.maSetPtInfo(htEnabled,ptNames,ht);            
            delete(app);
        end

        % Value changed function: SpecfyHeadLandmarkSwitch
        function SpecfyHeadLandmarkSwitchValueChanged(app, event)
            app.updateTable(1);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            HEADTAILCOL = 2;
            if indices(2)==HEADTAILCOL && ~event.PreviousData && event.NewData
                ht = app.UITable;
                dat = ht.Data;
                htcol = cell2mat(dat(:,HEADTAILCOL));
                if nnz(htcol)>1
                    htcol(:) = 0;
                    htcol(indices(1)) = 1;
                    ht.Data(:,HEADTAILCOL) = num2cell(htcol);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KeypointsUIFigure = uifigure('Visible', 'off');
            app.KeypointsUIFigure.Name = 'Keypoints';
            app.KeypointsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.KeypointsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.AddEdgeButton = uibutton(app.LeftPanel, 'push');
            app.AddEdgeButton.ButtonPushedFcn = createCallbackFcn(app, @AddEdgeButtonPushed, true);
            app.AddEdgeButton.Text = 'Add Edge';
            app.RemoveEdgeButton = uibutton(app.LeftPanel, 'push');
            app.RemoveEdgeButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveEdgeButtonPushed, true);
            app.RemoveEdgeButton.Text = 'Remove Edge';
            app.DoneButton = uibutton(app.LeftPanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.RightPanel = uipanel(app.GridLayout);
            app.SpecfyHeadLandmarkSwitchLabel = uilabel(app.RightPanel);
            app.SpecfyHeadLandmarkSwitchLabel.Text = {'Specfy Head Landmark'; ''};
            app.SpecfyHeadLandmarkSwitch = uiswitch(app.RightPanel, 'slider');
            app.SpecfyHeadLandmarkSwitch.ValueChangedFcn = createCallbackFcn(app, @SpecfyHeadLandmarkSwitchValueChanged, true);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'#'; 'Name'; 'Head'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            % Show the figure after all components are created
            app.KeypointsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = keypoints(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KeypointsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KeypointsUIFigure)
        end
    end
end